classdef numerical < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultsLabel                    matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        UpperLiimitbEditField           matlab.ui.control.NumericEditField
        UpperLiimitbEditFieldLabel      matlab.ui.control.Label
        LowerLimitaEditField            matlab.ui.control.NumericEditField
        LowerLimitaEditFieldLabel       matlab.ui.control.Label
        DerivativeforNRMEditField       matlab.ui.control.EditField
        DerivativeforNRMEditFieldLabel  matlab.ui.control.Label
        EquationEditField               matlab.ui.control.EditField
        EquationEditFieldLabel          matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
         % 1. READ INPUTS DIRECTLY FROM THE APP SCREEN
        f_str = app.EquationEditField.Value;
        df_str = app.DerivativeforNRMEditField.Value;

        % Convert text inputs into clean math expressions
        f = str2func(['@(x) ' f_str]);
        df = str2func(['@(x) ' df_str]);

        % Read live limits and guesses from numeric inputs
        a_input = app.LowerLimitaEditField.Value;
        b_input = app.UpperLiimitbEditField.Value;

        % Tolerances and iterations
        tol = 1e-5;
        max_iter = 15;
        history = [];

        % 2. IDENTIFY THE SELECTED INTERFACE METHOD
        selected_method = app.MethodDropDown.Value;

        if strcmp(selected_method, 'Bisection')
            %% --- RUN SIMPLE BISECTION METHOD ---
            a = a_input; 
            b = b_input; 
            
            for i = 1:max_iter
                c = (a + b) / 2;
                history(end+1) = c; 
                
                if abs(f(c)) < tol
                    break;
                elseif f(a) * f(c) < 0
                    b = c;
                else
                    a = c;
                end
            end
            final_val = c;
            plot_color = 'b';
            result_type = 'Calculated Root';
            y_label_text = 'Root Estimate';
            
        elseif strcmp(selected_method, 'Newton-Raphson')
            %% --- RUN SIMPLE NEWTON-RAPHSON METHOD ---
            x_curr = a_input; 
            
            for i = 1:max_iter
                history(end+1) = x_curr; 
                x_next = x_curr - f(x_curr)/df(x_curr);
                
                if abs(f(x_next)) < tol
                    x_curr = x_next;
                    break;
                end
                x_curr = x_next;
            end
            final_val = x_curr;
            plot_color = 'r';
            result_type = 'Calculated Root';
            y_label_text = 'Root Estimate';

        else
            %% --- RUN SIMPLE TRAPEZOIDAL METHOD (Integration) ---
            for strips = 1:max_iter
                h = (b_input - a_input) / strips;
                x_coords = linspace(a_input, b_input, strips + 1);
                y_coords = arrayfun(f, x_coords); 
                
                % Core Trapezoidal formula calculation
                area = (h / 2) * (y_coords(1) + 2 * sum(y_coords(2:end-1)) + y_coords(end));
                history(end+1) = area; 
            end
            final_val = history(end);
            plot_color = 'g'; 
            result_type = sprintf('Integral Area [from %.1f to %.1f]', a_input, b_input);
            y_label_text = 'Area Estimate';
        end

        % 3. SEND FINAL VALUES BACK TO THE INTERFACE (Using your ResultsLabel)
        app.ResultsLabel.Text = sprintf('%s: %.5f', result_type, final_val);

        % 4. GENERATE THE VISUAL PLOT
        plot(app.UIAxes, history, ['-o' plot_color], 'LineWidth', 2);
        xlabel(app.UIAxes, 'Iteration / Step Number');
        ylabel(app.UIAxes, y_label_text);
        title(app.UIAxes, sprintf('%s Tracking Path', selected_method));
        grid(app.UIAxes, 'on');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Bisection', 'Newton-Raphson', 'Trapezoidal Method', 'Option 4'};
            app.MethodDropDown.Value = 'Bisection';
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.EquationEditField.Value = 'x^2 -2';
            app.DerivativeforNRMEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeforNRMEditFieldLabel.Text = 'Derivative(for NRM)';
            app.DerivativeforNRMEditField = uieditfield(app.UIFigure, 'text');
            app.DerivativeforNRMEditField.Value = '2*x';
            app.LowerLimitaEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitaEditFieldLabel.Text = 'Lower Limit(a)';
            app.LowerLimitaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerLimitaEditField.Value = 1;
            app.UpperLiimitbEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLiimitbEditFieldLabel.Text = 'Upper Liimit(b)';
            app.UpperLiimitbEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLiimitbEditField.Value = 2;
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = numerical

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end